classdef App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure                matlab.ui.Figure
        tab_group             matlab.ui.container.TabGroup
        main                  matlab.ui.container.Tab
        pose_axes             matlab.ui.control.UIAxes
        flight_control_label  matlab.ui.control.Label
        thr_gauge_pp          matlab.ui.control.LinearGauge
        thr_gauge_pn          matlab.ui.control.LinearGauge
        thr_gauge_np          matlab.ui.control.LinearGauge
        thr_gauge_nn          matlab.ui.control.LinearGauge
        thr_label             matlab.ui.control.Label
        Tuner                 matlab.ui.container.Tab
        MinLabel              matlab.ui.control.Label
        thr_max_slider        matlab.ui.control.Slider
        MaxLabel              matlab.ui.control.Label
        KPLabel               matlab.ui.control.Label
        qx_kp_spinner         matlab.ui.control.Spinner
        KILabel               matlab.ui.control.Label
        qx_ki_spinner         matlab.ui.control.Spinner
        KDLabel               matlab.ui.control.Label
        qx_kd_spinner         matlab.ui.control.Spinner
        FFLabel               matlab.ui.control.Label
        qx_ff_spinner         matlab.ui.control.Spinner
        quat_x_label          matlab.ui.control.Label
        flight_params_label   matlab.ui.control.Label
        thr_min_slider        matlab.ui.control.Slider
        framerate_label       matlab.ui.control.Label
        time_label            matlab.ui.control.Label
    end

    properties (Access = protected)
        uav;            % UAV interface [UAV.Interface]
        plot_ang_pos;   % Pose plot [FramePlot3D]
        plot_ang_cmd;   % Pose cmd plot [FramePlot3D]
        frame_cnt;      % Frame count [cnts]
        frame_rate;     % Frame rate estimate [Hz]
        timer;          % Timer object [Timer]
    end
    
    methods (Access = public)
        function update(app, time)
            %UPDATE(app, time) Update GUI with latest state data
            %   Inputs:
            %       time = Current time [s]
            
            % Start timing on first frame
            if app.frame_cnt == 0
                app.timer.tic();
            end
            
            % Update graphics
            app.plot_ang_pos.update(app.uav.state.ang_pos);
            app.plot_ang_cmd.update(app.uav.cmd.ang_pos);
            app.thr_gauge_pp.Value = 100 * app.uav.state.thr_props(1);
            app.thr_gauge_pn.Value = 100 * app.uav.state.thr_props(2);
            app.thr_gauge_np.Value = 100 * app.uav.state.thr_props(3);
            app.thr_gauge_nn.Value = 100 * app.uav.state.thr_props(4);
            app.framerate_label.Text = sprintf('Framerate: %.1f [Hz]', app.frame_rate);
            app.time_label.Text = sprintf('Time: %.2f [s]', time);
            drawnow
            
            % Estimate frame rate
            app.frame_cnt = app.frame_cnt + 1;
            app.frame_rate = app.frame_cnt / app.timer.toc();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, uav)
            % Copy UAV
            app.uav = uav;
            
            % Format 3D pose plot
            hold(app.pose_axes, 'on');
            app.plot_ang_pos = FramePlot3D(1, 'r-', 'g-', 'b-', app.pose_axes);
            app.plot_ang_cmd = FramePlot3D(1, 'r--', 'g--', 'b--', app.pose_axes);
            app.plot_ang_pos.update(Quat());
            app.plot_ang_cmd.update(Quat());
            hold(app.pose_axes, 'off');
            zlabel(app.pose_axes, 'z');
            xlim(app.pose_axes, [-1, +1]);
            ylim(app.pose_axes, [-1, +1]);
            zlim(app.pose_axes, [-1, +1]);
            view(app.pose_axes, -20, +35);
            grid(app.pose_axes, 'on')
            camproj(app.pose_axes, 'perspective');
            axis(app.pose_axes, 'square');
            drawnow
            
            % Frame rate tracking
            app.frame_cnt = 0;
            app.frame_rate = 0;
            app.timer = Timer();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            app.figure.Name = 'UI Figure';
            app.tab_group = uitabgroup(app.figure);
            app.main = uitab(app.tab_group);
            app.main.Title = 'Main';
            app.pose_axes = uiaxes(app.main);
            title(app.pose_axes, 'Pose')
            xlabel(app.pose_axes, 'x')
            ylabel(app.pose_axes, 'y')
            app.flight_control_label = uilabel(app.main);
            app.flight_control_label.Text = 'UAV Flight Controller';
            app.thr_gauge_pp = uigauge(app.main, 'linear');
            app.thr_gauge_pn = uigauge(app.main, 'linear');
            app.thr_gauge_np = uigauge(app.main, 'linear');
            app.thr_gauge_nn = uigauge(app.main, 'linear');
            app.thr_label = uilabel(app.main);
            app.thr_label.Text = 'Throttles';
            app.Tuner = uitab(app.tab_group);
            app.Tuner.Title = 'Tuner';
            app.flight_params_label = uilabel(app.Tuner);
            app.flight_params_label.Text = 'UAV Flight Parameters';
            app.MinLabel = uilabel(app.Tuner);
            app.MinLabel.Text = 'Min';
            app.thr_min_slider = uislider(app.Tuner);
            app.thr_max_slider = uislider(app.Tuner);
            app.MaxLabel = uilabel(app.Tuner);
            app.MaxLabel.Text = 'Max';
            app.KPLabel = uilabel(app.Tuner);
            app.KPLabel.Text = 'KP';
            app.qx_kp_spinner = uispinner(app.Tuner);
            app.KILabel = uilabel(app.Tuner);
            app.KILabel.Text = 'KI';
            app.qx_ki_spinner = uispinner(app.Tuner);
            app.KDLabel = uilabel(app.Tuner);
            app.KDLabel.Text = 'KD';
            app.qx_kd_spinner = uispinner(app.Tuner);
            app.FFLabel = uilabel(app.Tuner);
            app.FFLabel.Text = 'FF';
            app.qx_ff_spinner = uispinner(app.Tuner);
            app.quat_x_label = uilabel(app.Tuner);
            app.quat_x_label.Text = 'Quaternion X';
            app.framerate_label = uilabel(app.figure);
            app.framerate_label.Text = 'Framerate: 00.0 [Hz]';
            app.time_label = uilabel(app.figure);
            app.time_label.Text = 'Time: 0.00 [s]';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end