classdef Gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure           matlab.ui.Figure
        framerate_label  matlab.ui.control.Label
        main_label       matlab.ui.control.Label
        time_label       matlab.ui.control.Label
        pose_axes        matlab.ui.control.UIAxes
        tab_group        matlab.ui.container.TabGroup
        thr_tab          matlab.ui.container.Tab
        thr_gauge_pp     matlab.ui.control.LinearGauge
        thr_gauge_pn     matlab.ui.control.LinearGauge
        thr_gauge_np     matlab.ui.control.LinearGauge
        thr_gauge_nn     matlab.ui.control.LinearGauge
        limits_tab       matlab.ui.container.Tab
        thr_min_label    matlab.ui.control.Label
        thr_min_slider   matlab.ui.control.Slider
        thr_max_label    matlab.ui.control.Label
        thr_max_slider   matlab.ui.control.Slider
        gains_tab        matlab.ui.container.Tab
        qx_label         matlab.ui.control.Label
        qx_kp_label      matlab.ui.control.Label
        qx_kp_spinner    matlab.ui.control.Spinner
        qx_ki_label      matlab.ui.control.Label
        qx_ki_spinner    matlab.ui.control.Spinner
        qx_kd_label      matlab.ui.control.Label
        qx_kd_spinner    matlab.ui.control.Spinner
        qx_ff_label      matlab.ui.control.Label
        qx_ff_spinner    matlab.ui.control.Spinner
        qy_label         matlab.ui.control.Label
        qy_kp_label      matlab.ui.control.Label
        qy_kp_spinner    matlab.ui.control.Spinner
        qy_ki_label      matlab.ui.control.Label
        qy_ki_spinner    matlab.ui.control.Spinner
        qy_kd_label      matlab.ui.control.Label
        qy_kd_spinner    matlab.ui.control.Spinner
        qy_ff_label      matlab.ui.control.Label
        qy_ff_spinner    matlab.ui.control.Spinner
        qz_label         matlab.ui.control.Label
        qz_kp_label      matlab.ui.control.Label
        qz_kp_spinner    matlab.ui.control.Spinner
        qz_ki_label      matlab.ui.control.Label
        qz_ki_spinner    matlab.ui.control.Spinner
        qz_kd_label      matlab.ui.control.Label
        qz_kd_spinner    matlab.ui.control.Spinner
        qz_ff_label      matlab.ui.control.Label
        qz_ff_spinner    matlab.ui.control.Spinner
        get_button       matlab.ui.control.Button
        set_button       matlab.ui.control.Button
        state_label      matlab.ui.control.Label
    end

    properties (Access = protected)
        uav;            % UAV interface [UAV.Interface]
        plot_ang_pos;   % Pose plot [FramePlot3D]
        plot_ang_cmd;   % Pose cmd plot [FramePlot3D]
        frame_cnt;      % Frame count [cnts]
        frame_rate;     % Frame rate estimate [Hz]
        timer;          % Timer object [Timer]
    end
    
    methods (Access = public)
        function update(app, time)
            %UPDATE(app, time) Update GUI with latest state data
            %   Inputs:
            %       time = Current time [s]
            
            % Imports
            import('uav.state.Enum')
            
            % Start timing on first frame
            if app.frame_cnt == 0
                app.timer.tic();
            end
            
            % Update graphics
            state = app.uav.state;
            app.plot_ang_pos.update(state.ang_pos.mat_rot());
            app.plot_ang_cmd.update(app.uav.cmd.ang_pos.mat_rot());
            app.thr_gauge_pp.Value = 100 * state.thr_props(1);
            app.thr_gauge_pn.Value = 100 * state.thr_props(2);
            app.thr_gauge_np.Value = 100 * state.thr_props(3);
            app.thr_gauge_nn.Value = 100 * state.thr_props(4);
            app.state_label.Text = sprintf('State: %s', char(state.enum));
            app.time_label.Text = sprintf('Time: %.2f [s]', time);
            app.framerate_label.Text = sprintf('Framerate: %.1f [Hz]', app.frame_rate);
            app.set_button.Enable = (app.uav.state.enum == Enum.Disabled);
            drawnow
            
            % Estimate frame rate
            app.frame_cnt = app.frame_cnt + 1;
            app.frame_rate = app.frame_cnt / app.timer.toc();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, uav)
            % Imports
            import('live_plot.Frame3D');
            import('timing.Timer')
            
            % Copy UAV
            app.uav = uav;
            
            % Set figure location
            % app.figure.Units = 'normalized';
            % app.figure.Position = [0.5005 0.0380 0.4990 0.8833];
            
            % Generate 3D pose plots
            hold(app.pose_axes, 'on');
            
            % Attitude position plot
            app.plot_ang_pos = Frame3D(1, app.pose_axes);
            app.plot_ang_pos.plot_x.plot_.Color = 'r';
            app.plot_ang_pos.plot_y.plot_.Color = 'g';
            app.plot_ang_pos.plot_z.plot_.Color = 'b';
            
            % Attitude command plot
            app.plot_ang_cmd = Frame3D(1, app.pose_axes);
            app.plot_ang_cmd.plot_x.plot_.Color = 'r';
            app.plot_ang_cmd.plot_y.plot_.Color = 'g';
            app.plot_ang_cmd.plot_z.plot_.Color = 'b';
            app.plot_ang_cmd.plot_x.plot_.LineStyle = '--';
            app.plot_ang_cmd.plot_y.plot_.LineStyle = '--';
            app.plot_ang_cmd.plot_z.plot_.LineStyle = '--';
            
            % Format plot
            hold(app.pose_axes, 'off');
            zlabel(app.pose_axes, 'z');
            xlim(app.pose_axes, [-1, +1]);
            ylim(app.pose_axes, [-1, +1]);
            zlim(app.pose_axes, [-1, +1]);
            view(app.pose_axes, -20, +35);
            grid(app.pose_axes, 'on')
            camproj(app.pose_axes, 'perspective');
            axis(app.pose_axes, 'square');
            drawnow
            
            % Load params into GUI
            app.get_buttonButtonPushed();
            
            % Frame rate tracking
            app.frame_cnt = 0;
            app.frame_rate = 0;
            app.timer = Timer();
        end

        % Button pushed function: get_button
        function get_buttonButtonPushed(app, event)
            % Get UAV params
            params = app.uav.params;
            
            % Update throttle limit sliders
            app.thr_min_slider.Value = params.thr_min * 100.0;
            app.thr_max_slider.Value = params.thr_max * 100.0;
            
            % Update Quat-x PID spinners
            app.qx_kp_spinner.Value = params.qx_kp;
            app.qx_ki_spinner.Value = params.qx_ki;
            app.qx_kd_spinner.Value = params.qx_kd;
            app.qx_ff_spinner.Value = params.qx_ff;
            
            % Update Quat-y PID spinners
            app.qy_kp_spinner.Value = params.qy_kp;
            app.qy_ki_spinner.Value = params.qy_ki;
            app.qy_kd_spinner.Value = params.qy_kd;
            app.qy_ff_spinner.Value = params.qy_ff;
            
            % Update Quat-z PID spinners
            app.qz_kp_spinner.Value = params.qz_kp;
            app.qz_ki_spinner.Value = params.qz_ki;
            app.qz_kd_spinner.Value = params.qz_kd;
            app.qz_ff_spinner.Value = params.qz_ff;
        end

        % Button pushed function: set_button
        function set_buttonButtonPushed(app, event)
            % Imports
            import('uav.Params')
            
            % Create params object
            params = Params(app.uav.params.file_name);
            
            % Get throttle limit sliders
            params.thr_min = 0.01 * app.thr_min_slider.Value;
            params.thr_max = 0.01 * app.thr_max_slider.Value;
            
            % Get Quat-x PID gains
            params.qx_kp = app.qx_kp_spinner.Value;
            params.qx_ki = app.qx_ki_spinner.Value;
            params.qx_kd = app.qx_kd_spinner.Value;
            params.qx_ff = app.qx_ff_spinner.Value;
            
            % Get Quat-y PID gains
            params.qy_kp = app.qy_kp_spinner.Value;
            params.qy_ki = app.qy_ki_spinner.Value;
            params.qy_kd = app.qy_kd_spinner.Value;
            params.qy_ff = app.qy_ff_spinner.Value;
            
            % Get Quat-z PID gains
            params.qz_kp = app.qz_kp_spinner.Value;
            params.qz_ki = app.qz_ki_spinner.Value;
            params.qz_kd = app.qz_kd_spinner.Value;
            params.qz_ff = app.qz_ff_spinner.Value;
            
            % Update UAV params
            app.uav.set_params(params);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure = uifigure('Visible', 'off');
            app.figure.Name = 'UI Figure';
            app.framerate_label = uilabel(app.figure);
            app.framerate_label.Text = 'Framerate: 00.0 [Hz]';
            app.main_label = uilabel(app.figure);
            app.main_label.Text = 'UAV Flight Controller';
            app.time_label = uilabel(app.figure);
            app.time_label.Text = 'Time: 0.00 [s]';
            app.pose_axes = uiaxes(app.figure);
            title(app.pose_axes, 'Pose')
            xlabel(app.pose_axes, 'x')
            ylabel(app.pose_axes, 'y')
            app.tab_group = uitabgroup(app.figure);
            app.thr_tab = uitab(app.tab_group);
            app.thr_tab.Title = 'Throttles';
            app.thr_gauge_pp = uigauge(app.thr_tab, 'linear');
            app.thr_gauge_pn = uigauge(app.thr_tab, 'linear');
            app.thr_gauge_np = uigauge(app.thr_tab, 'linear');
            app.thr_gauge_nn = uigauge(app.thr_tab, 'linear');
            app.limits_tab = uitab(app.tab_group);
            app.limits_tab.Title = 'Limits';
            app.thr_min_label = uilabel(app.limits_tab);
            app.thr_min_label.Text = 'Throttle Min';
            app.thr_min_slider = uislider(app.limits_tab);
            app.thr_min_slider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.thr_min_slider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.thr_min_slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.thr_max_label = uilabel(app.limits_tab);
            app.thr_max_label.Text = 'Throttle Max';
            app.thr_max_slider = uislider(app.limits_tab);
            app.thr_max_slider.MajorTicks = [0 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100];
            app.thr_max_slider.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30', '35', '40', '45', '50', '55', '60', '65', '70', '75', '80', '85', '90', '95', '100'};
            app.thr_max_slider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17 18 19 20 21 22 23 24 25 26 27 28 29 30 31 32 33 34 35 36 37 38 39 40 41 42 43 44 45 46 47 48 49 50 51 52 53 54 55 56 57 58 59 60 61 62 63 64 65 66 67 68 69 70 71 72 73 74 75 76 77 78 79 80 81 82 83 84 85 86 87 88 89 90 91 92 93 94 95 96 97 98 99 100];
            app.gains_tab = uitab(app.tab_group);
            app.gains_tab.Title = 'Gains';
            app.qx_label = uilabel(app.gains_tab);
            app.qx_label.Text = 'Quaternion X';
            app.qx_kp_label = uilabel(app.gains_tab);
            app.qx_kp_label.Text = 'KP';
            app.qx_kp_spinner = uispinner(app.gains_tab);
            app.qx_kp_spinner.Step = 0.01;
            app.qx_kp_spinner.Limits = [0 1];
            app.qx_ki_label = uilabel(app.gains_tab);
            app.qx_ki_label.Text = 'KI';
            app.qx_ki_spinner = uispinner(app.gains_tab);
            app.qx_ki_spinner.Step = 0.02;
            app.qx_ki_spinner.Limits = [0 2];
            app.qx_kd_label = uilabel(app.gains_tab);
            app.qx_kd_label.Text = 'KD';
            app.qx_kd_spinner = uispinner(app.gains_tab);
            app.qx_kd_spinner.Step = 0.002;
            app.qx_kd_spinner.Limits = [0 0.2];
            app.qx_ff_label = uilabel(app.gains_tab);
            app.qx_ff_label.Text = 'FF';
            app.qx_ff_spinner = uispinner(app.gains_tab);
            app.qx_ff_spinner.Step = 0.01;
            app.qx_ff_spinner.Limits = [-0.5 0.5];
            app.qy_label = uilabel(app.gains_tab);
            app.qy_label.Text = 'Quaternion Y';
            app.qy_kp_label = uilabel(app.gains_tab);
            app.qy_kp_label.Text = 'KP';
            app.qy_kp_spinner = uispinner(app.gains_tab);
            app.qy_kp_spinner.Step = 0.01;
            app.qy_kp_spinner.Limits = [0 1];
            app.qy_ki_label = uilabel(app.gains_tab);
            app.qy_ki_label.Text = 'KI';
            app.qy_ki_spinner = uispinner(app.gains_tab);
            app.qy_ki_spinner.Step = 0.02;
            app.qy_ki_spinner.Limits = [0 2];
            app.qy_kd_label = uilabel(app.gains_tab);
            app.qy_kd_label.Text = 'KD';
            app.qy_kd_spinner = uispinner(app.gains_tab);
            app.qy_kd_spinner.Step = 0.002;
            app.qy_kd_spinner.Limits = [0 0.2];
            app.qy_ff_label = uilabel(app.gains_tab);
            app.qy_ff_label.Text = 'FF';
            app.qy_ff_spinner = uispinner(app.gains_tab);
            app.qy_ff_spinner.Step = 0.01;
            app.qy_ff_spinner.Limits = [-0.5 0.5];
            app.qz_label = uilabel(app.gains_tab);
            app.qz_label.Text = 'Quaternion Z';
            app.qz_kp_label = uilabel(app.gains_tab);
            app.qz_kp_label.Text = 'KP';
            app.qz_kp_spinner = uispinner(app.gains_tab);
            app.qz_kp_spinner.Step = 0.01;
            app.qz_kp_spinner.Limits = [0 1];
            app.qz_ki_label = uilabel(app.gains_tab);
            app.qz_ki_label.Text = 'KI';
            app.qz_ki_spinner = uispinner(app.gains_tab);
            app.qz_ki_spinner.Step = 0.02;
            app.qz_ki_spinner.Limits = [0 2];
            app.qz_kd_label = uilabel(app.gains_tab);
            app.qz_kd_label.Text = 'KD';
            app.qz_kd_spinner = uispinner(app.gains_tab);
            app.qz_kd_spinner.Step = 0.002;
            app.qz_kd_spinner.Limits = [0 0.2];
            app.qz_ff_label = uilabel(app.gains_tab);
            app.qz_ff_label.Text = 'FF';
            app.qz_ff_spinner = uispinner(app.gains_tab);
            app.qz_ff_spinner.Step = 0.01;
            app.qz_ff_spinner.Limits = [-0.5 0.5];
            app.get_button = uibutton(app.figure, 'push');
            app.get_button.ButtonPushedFcn = createCallbackFcn(app, @get_buttonButtonPushed, true);
            app.get_button.Text = 'Get';
            app.set_button = uibutton(app.figure, 'push');
            app.set_button.ButtonPushedFcn = createCallbackFcn(app, @set_buttonButtonPushed, true);
            app.set_button.Text = 'Set';
            app.state_label = uilabel(app.figure);
            app.state_label.Text = 'State: Disabled';
            % Show the figure after all components are created
            app.figure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Gui(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure)
        end
    end
end